classdef DoAnPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TimNghiem                     matlab.ui.container.Tab
        SlnlpLabel                    matlab.ui.control.Label
        KtqunghimLabel                matlab.ui.control.Label
        ChnphngphptnhLabel            matlab.ui.control.Label
        NhpkhongsaisLabel             matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        SoLanLap                      matlab.ui.control.NumericEditField
        KetQuaNghiem                  matlab.ui.control.NumericEditField
        NhapSaiSo                     matlab.ui.control.NumericEditField
        bEditField                    matlab.ui.control.NumericEditField
        aEditField                    matlab.ui.control.NumericEditField
        KhongphnlinghimLabel          matlab.ui.control.Label
        VeButton                      matlab.ui.control.Button
        ChonPPT                       matlab.ui.control.DropDown
        NhapPT                        matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NiSuyTab                      matlab.ui.container.Tab
        xNoiSuy                       matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        yNoiSuy                       matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        KetQuaDaThucNoiSuy            matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        VeNoiSuy                      matlab.ui.control.Button
        KetQuaNoiSuy                  matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        GiaTriCanNoiSuy               matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        ChonPPNoiSuy                  matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        HoiQuyTab                     matlab.ui.container.Tab
        VeHoiQuy                      matlab.ui.control.Button
        KetQuaDuDoan                  matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        KetQuaPTHoiQuy                matlab.ui.control.EditField
        KtquphngtrnhLabel             matlab.ui.control.Label
        NhapGTDuDoan                  matlab.ui.control.NumericEditField
        NhpgitrcndonLabel             matlab.ui.control.Label
        ChonPPHoiQuy                  matlab.ui.control.DropDown
        ChnphngphpDropDownLabel_2     matlab.ui.control.Label
        yHoiQuy                       matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_4      matlab.ui.control.Label
        xHoiQuy                       matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_4      matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        DaoHamTab                     matlab.ui.container.Tab
        ChonSaiSoDaoHam               matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel      matlab.ui.control.Label
        TinhDaoHam                    matlab.ui.control.Button
        KetQuaDaoHam                  matlab.ui.control.NumericEditField
        KtquEditFieldLabel            matlab.ui.control.Label
        GiaTriCanTinhDaoHam           matlab.ui.control.NumericEditField
        NhpgitrcntnhLabel             matlab.ui.control.Label
        ChonPPDaoHam                  matlab.ui.control.DropDown
        ChnphngphpLabel               matlab.ui.control.Label
        hDaoHam                       matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        HamSoDaoHam                   matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        yDaoHam                       matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2      matlab.ui.control.Label
        xDaoHam                       matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2      matlab.ui.control.Label
        TichPhanTab                   matlab.ui.container.Tab
        NhapN                         matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        Can_b                         matlab.ui.control.NumericEditField
        NhpcnbEditFieldLabel          matlab.ui.control.Label
        Can_a                         matlab.ui.control.NumericEditField
        NhpcnaEditFieldLabel          matlab.ui.control.Label
        TinhTichPhan                  matlab.ui.control.Button
        KetQuaTichPhan                matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2          matlab.ui.control.Label
        ChonPPTichPhan                matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapHamSoTichPhan             matlab.ui.control.EditField
        NhphmsEditFieldLabel_2        matlab.ui.control.Label
        yTichPhan                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3      matlab.ui.control.Label
        xTichPhan                     matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3      matlab.ui.control.Label
        GiithiunhmTab                 matlab.ui.container.Tab
        Name                          matlab.ui.control.Label
        Title1                        matlab.ui.control.Label
        Title                         matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        Name1                         matlab.ui.control.Label
        Name2                         matlab.ui.control.Label
        Name3                         matlab.ui.control.Label
        Name4                         matlab.ui.control.Label
    end

    
    methods (Access = public)
        
    function [c, n] = chiadoi(app,fx, a ,b ,saiso)
        n = 0;
        fxi = str2func(['@(x)', fx]);
        while true 
        c = (a + b)/2;
        if(fxi(a)*fxi(c) < 0)
            b = c;
        else 
            a = c;
        end
        n = n + 1;
        if abs(b-a) < saiso
            break;
        end
        end
        end

    function[ x1, n]= lap(app,fx, a, saiso) 
        n = 0;  
        lambda = 1;   % có thể chỉnh nếu không hội tụ
    
        % fx là chuỗi → đổi thành function
        f = str2func(['@(x)', fx]);
    
        % hàm lặp
        fp = @(x) x - lambda * f(x);
        x0 = a; 
        for i = 1:2000 
        x1 = fp(x0); 
        n = n+1; 
        if abs(x1 - x0) < saiso 
        break; 
        end 
        x0 = x1; 
        end 
        end

    function [x1, n] = Newton(app,fx, a, b, saiso) 
        syms x; 
        f = str2func(['@(x)', fx]);        
        df = matlabFunction(diff(str2sym(fx), x));   
        f2 = matlabFunction(diff(diff(str2sym(fx), x)));  
        if f(a) * f2(a) > 0 
        x0 = a; 
        else 
        x0 = b; 
        end 
        n = 0; 
        while true 
        n = n + 1; 
        x1 = x0 - f(x0) / df(x0);   
        if abs(x1 - x0) < saiso 
        break; 
        end 
        x0 = x1; 
        if df(x0) == 0 
        warning('Đạo hàm bằng 0 tại x = %.6f, phương pháp dừng.', x0); 
        break; 
        end 
        end 
    end 


    %--Nội suy--
    function result = Lagrange(app,xa, ya, x) 
    n = length(xa); 
    sum = 0;
    for i = 1:n 
        product = ya(i); 
        for j = 1:n 
            if i ~= j 
                product = product * (x - xa(j)) / (xa(i) - xa(j)); 
            end 
        end 
        sum = sum + product; 
    end   
    result = sum; 
    end

    function P = LagrangeSym(app,xa, ya)    
    syms x; 
    n = length(xa); 
    P = 0; 
    for i = 1:n 
    L = 1;  
    for j = 1:n 
    if i ~= j 
    L = L * (x - xa(j)) / (xa(i) - xa(j)); 
    end 
    end 
    P = P + ya(i) * L; 
    end 
    P = simplify(P); 
    end

    function d = DividedDifference(app,xa, ya) 
    n = length(ya); 
    d_table = zeros(n, n); 
    d_table(:, 1) = ya(:);  
    for j = 2:n 
    for i = 1:n-j+1 
    d_table(i, j) = (d_table(i+1, j-1) - d_table(i, j-1)) / (xa(i+j-1) - xa(i)); 
    end 
    end 
    d = d_table(1, :); 
    end

    function result = NewtonForm(app,xa, da, x) 
    n = length(da); 
    result = da(n); 
    for i = n-1:-1:1 
    result = result * (x - xa(i)) + da(i); 
    end 
    end

    function result = NewtonInterpolation(app,xa, ya, x) 
    da = app.DividedDifference(xa, ya);     
    result = app.NewtonForm(xa, da, x); 
    end

    function P = NewtonSym(app,xa, ya) 
    syms x; 
    n = length(xa); 
    d = app.DividedDifference(xa, ya); 
    P = d(1); 
    term = 1; 
    for i = 2:n 
    term = term * (x - xa(i-1)); 
    P = P + d(i) * term; 
    end  
    P = simplify(P); 
    end

    %--Tích phân--
    function I = TichPhanHinhThang(app,input1, input2, input3, input4)
    % TICH PHAN HINH THANG
    % Cach 1: TichPhanHinhThang(fx, a, b, N)
    % Cach 2: TichPhanHinhThang(x, y)

    %% ===== XAC DINH KIEU DU LIEU =====
    if isa(input1,'function_handle')
        % ===== NHAP HAM SO =====
        fx = input1;
        a  = input2;
        b  = input3;
        N  = input4;

        h = (b - a)/N;
        x = a:h:b;
        y = fx(x);

    else
        % ===== NHAP MANG x, y =====
        x = input1;
        y = input2;

        N = length(x) - 1;
        h = x(2) - x(1);   % gia su x cach deu
    end

    %% ===== CONG THUC HINH THANG =====
    sum_mid = 0;
    for i = 2:N
        sum_mid = sum_mid + y(i);
    end

    I = (h/2) * ( y(1) + y(end) + 2*sum_mid );
    end

    function I = TichPhanSimpson13(app,input1, input2, input3, input4)
% TICH PHAN SIMPSON 1/3
% Cach 1: TichPhanSimpson13(fx, a, b, N)
% Cach 2: TichPhanSimpson13(x, y)

    % ===== XAC DINH KIEU DU LIEU =====
    if isa(input1,'function_handle')
        % ===== NHAP HAM SO =====
        fx = input1;
        a  = input2;
        b  = input3;
        N  = input4;

        if mod(N,2) ~= 0
            error('N phai la so chan cho Simpson 1/3');
        end

        h = (b - a)/N;
        x = a:h:b;
        y = fx(x);

    else
        % ===== NHAP MANG x, y =====
        x = input1;
        y = input2;

        N = length(x) - 1;

        if mod(N,2) ~= 0
            error('N = length(x)-1 phai la so chan cho Simpson 1/3');
        end

        h = x(2) - x(1);   % gia su x cach deu
    end

    % ===== CONG THUC SIMPSON 1/3 =====
    sum_le = 0;    % he so 4
    for i = 2:2:N
        sum_le = sum_le + y(i);
    end

    sum_chan = 0;  % he so 2
    for i = 3:2:N-1
        sum_chan = sum_chan + y(i);
    end

    I = (h/3) * ( y(1) + y(end) + 4*sum_le + 2*sum_chan );
    end

    function I = TichPhanSimpson38(app,input1, input2, input3, input4)
% TICH PHAN SIMPSON 3/8
% - Cach 1: TichPhanSimpson38(fx, a, b, N)
% - Cach 2: TichPhanSimpson38(x, y)

    % ===== PHAN 1: XAC DINH KIEU DU LIEU =====
    if isa(input1, 'function_handle')
        % ===== NHAP HAM SO =====
        fx = input1;
        a  = input2;
        b  = input3;
        N  = input4;

        if mod(N,3) ~= 0
            error('N phai chia het cho 3');
        end

        h = (b - a) / N;

        % tao vector x
        x = a:h:b;

        % tinh y
        y = fx(x);

    else
        % ===== NHAP MANG x, y =====
        x = input1;
        y = input2;

        N = length(x) - 1;

        if mod(N,3) ~= 0
            error('So khoang N = length(x)-1 phai chia het cho 3');
        end

        % buoc luoi (gia su x cach deu)
        h = x(2) - x(1);
    end

    % ===== PHAN 2: CONG THUC SIMPSON 3/8 =====
    sum3 = 0;   % he so 3
    for i = 2:N
        if mod(i-1,3) ~= 0
            sum3 = sum3 + y(i);
        end
    end

    sum2 = 0;   % he so 2
    for i = 4:3:N
        sum2 = sum2 + y(i);
    end

    I = (3*h/8) * ( y(1) + y(end) + 3*sum3 + 2*sum2 );
    end

    %--Hồi Quy--
    function [a1, a0, r2] = HoiQuyTuyenTinh(app,x, y)

    n = length(x);
    sumx  = 0;
    sumy  = 0;
    sumxy = 0;
    sumx2 = 0;

    for i = 1:n
        sumx  = sumx + x(i);
        sumy  = sumy + y(i);
        sumxy = sumxy + x(i)*y(i);
        sumx2 = sumx2 + x(i)*x(i);
    end

    xm = sumx / n;
    ym = sumy / n;

    a1 = (n*sumxy - sumx*sumy) / (n*sumx2 - sumx*sumx);
    a0 = ym - a1 * xm;
    st = 0;
    sr = 0;

    for i = 1:n
        st = st + (y(i) - ym)^2;
        sr = sr + (y(i) - a1*x(i) - a0)^2;
    end

    r2 = (st - sr) / st;

end

    function model = HoiQuyHamMu(app,x, y) 
        n = length(x);
        r21 = -inf;
    
        if all(y > 0)
            X1 = x;
            Y1 = log(y);
    
            sumX = 0; sumY = 0; sumXY = 0; sumX2 = 0;
            for i = 1:n
                sumX  = sumX  + X1(i);
                sumY  = sumY  + Y1(i);
                sumXY = sumXY + X1(i)*Y1(i);
                sumX2 = sumX2 + X1(i)*X1(i);
            end
    
            Xm = sumX/n;
            Ym = sumY/n;
    
            b1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX^2);
            A01 = Ym - b1*Xm;
            a1 = exp(A01);
    
            st = 0; sr = 0;
            for i = 1:n
                st = st + (Y1(i) - Ym)^2;
                sr = sr + (Y1(i) - (A01 + b1*X1(i)))^2;
            end
            r21 = (st - sr)/st;
        end

        r22 = -inf;
    
        if all(x > 0) && all(y > 0)
            X2 = log(x);
            Y2 = log(y);
    
            sumX = 0; sumY = 0; sumXY = 0; sumX2 = 0;
            for i = 1:n
                sumX  = sumX  + X2(i);
                sumY  = sumY  + Y2(i);
                sumXY = sumXY + X2(i)*Y2(i);
                sumX2 = sumX2 + X2(i)*X2(i);
            end
    
            Xm = sumX/n;
            Ym = sumY/n;    
            b2 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX^2);
            A02 = Ym - b2*Xm;
            a2 = exp(A02);    
            st = 0; sr = 0;
            for i = 1:n
                st = st + (Y2(i) - Ym)^2;
                sr = sr + (Y2(i) - (A02 + b2*X2(i)))^2;
            end
            r22 = (st - sr)/st;
        end

        if r21 >= r22
            model.type = 'exp';
            model.a = a1;
            model.b = b1;
            model.r2 = r21;
        else
            model.type = 'power';
            model.a = a2;
            model.b = b2;
            model.r2 = r22;
        end
    end

    function model = HoiQuyMu_e(app,x, y)
% Hồi quy y = a*e^(b*x)
% Cách gọi: model = HoiQuyMu_e(x, y)

    n = length(x);

    % Tuyến tính hóa
    X = x;
    Y = log(y);

    sumX  = 0;
    sumY  = 0;
    sumXY = 0;
    sumX2 = 0;

    for i = 1:n
        sumX  = sumX  + X(i);
        sumY  = sumY  + Y(i);
        sumXY = sumXY + X(i)*Y(i);
        sumX2 = sumX2 + X(i)*X(i);
    end

    Xm = sumX / n;
    Ym = sumY / n;

    A1 = (n*sumXY - sumX*sumY) / (n*sumX2 - sumX^2);
    A0 = Ym - A1*Xm;

    % Tham số mô hình
    a = exp(A0);
    b = A1;

    % Tính r^2
    st = 0;
    sr = 0;

    for i = 1:n
        st = st + (Y(i) - Ym)^2;
        sr = sr + (Y(i) - (A1*X(i) + A0))^2;
    end

    r2 = (st - sr) / st;

    % Đóng gói kết quả
    model.a  = a;
    model.b  = b;
    model.r2 = r2;
    model.type = 'y = a*e^(b*x)';
end

    function df = DaoHamTien(app,input1, input2, input3, input4)
    x0 = input3;          % Gia tri can tinh
    loai_sai_so = input4; % 1 = O(h), 2 = O(h^2)
    
    % Bien luu cac gia tri y can thiet: y0=f(x), y1=f(x+h), y2=f(x+2h)
    y0 = 0; y1 = 0; y2 = 0; 
    h = 0;

    if isnumeric(input1) && length(input1) > 1
        % ----- TRUONG HOP 1: DU LIEU BANG (MANG SO) -----
        xa = input1; 
        ya = input2;
        
        idx = find(xa == x0, 1);
        if isempty(idx), error('Khong tim thay x trong du lieu!'); end
        
        h = xa(2) - xa(1); % Tinh buoc h tu du lieu
        
        % Kiem tra bien va lay gia tri y
        n = length(xa);
        if idx > n-1
             error('Khong du diem phia sau de tinh dao ham Tien O(h)'); 
        end
        
        y0 = ya(idx);
        y1 = ya(idx+1);
        
        if loai_sai_so == 2
            if idx > n-2
                error('Khong du diem phia sau de tinh dao ham Tien O(h^2)');
            end
            y2 = ya(idx+2);
        end

    else
        % ----- TRUONG HOP 2: HAM SO (STRING/HANDLE) -----
        ham_str = input1;
        h = input2;
        
        % Chuyen chuoi thanh ham
        if isa(ham_str, 'function_handle')
            f = ham_str;
        else
            f = str2func(['@(x) ' ham_str]);
        end
        
        % Tinh cac gia tri y
        y0 = f(x0);
        y1 = f(x0 + h);
        if loai_sai_so == 2
            y2 = f(x0 + 2*h);
        end
    end

    %% ===== CONG THUC DAO HAM TIEN =====
    if loai_sai_so == 1
        % Sai so O(h): (y(i+1) - y(i)) / h
        df = (y1 - y0) / h;
    else
        % Sai so O(h^2): (-3y(i) + 4y(i+1) - y(i+2)) / 2h
        df = (-3*y0 + 4*y1 - y2) / (2*h);
    end
    end

    function df = DaoHamLui(app,input1, input2, input3, input4)
% DAO HAM LUI (BACKWARD DIFFERENCE)
% Cach 1: DaoHamLui(ham_so, h, x0, sai_so)
% Cach 2: DaoHamLui(x, y, x0, sai_so)

    %% ===== XAC DINH KIEU DU LIEU & CHUAN BI BIEN =====
    x0 = input3;
    loai_sai_so = input4;
    
    % Bien luu: y0=f(x), y_1=f(x-h), y_2=f(x-2h)
    y0 = 0; y_1 = 0; y_2 = 0;
    h = 0;

    if isnumeric(input1) && length(input1) > 1
        % ----- DU LIEU BANG -----
        xa = input1; ya = input2;
        idx = find(xa == x0, 1);
        if isempty(idx), error('Khong tim thay x trong du lieu!'); end
        
        h = xa(2) - xa(1);
        
        if idx < 2
            error('Khong du diem phia truoc de tinh dao ham Lui O(h)');
        end
        
        y0 = ya(idx);
        y_1 = ya(idx-1);
        
        if loai_sai_so == 2
            if idx < 3
                error('Khong du diem phia truoc de tinh dao ham Lui O(h^2)');
            end
            y_2 = ya(idx-2);
        end
        
    else
        % ----- HAM SO -----
        ham_str = input1; h = input2;
        if isa(ham_str, 'function_handle'), f = ham_str; else, f = str2func(['@(x) ' ham_str]); end
        
        y0 = f(x0);
        y_1 = f(x0 - h);
        if loai_sai_so == 2
            y_2 = f(x0 - 2*h);
        end
    end

    %% ===== CONG THUC DAO HAM LUI =====
    if loai_sai_so == 1
        % Sai so O(h): (y(i) - y(i-1)) / h
        df = (y0 - y_1) / h;
    else
        % Sai so O(h^2): (3y(i) - 4y(i-1) + y(i-2)) / 2h
        df = (3*y0 - 4*y_1 + y_2) / (2*h);
    end
    end

    function df = DaoHamTrungTam(app,input1, input2, input3, input4)
% DAO HAM TRUNG TAM (CENTRAL DIFFERENCE)
% Cach 1: DaoHamTrungTam(ham_so, h, x0, sai_so)
% Cach 2: DaoHamTrungTam(x, y, x0, sai_so)

    %% ===== XAC DINH KIEU DU LIEU & CHUAN BI BIEN =====
    x0 = input3;
    % loai_sai_so = input4; % Phuong phap nay mac dinh la O(h^2)
    
    % Bien luu: y_1=f(x-h), y1=f(x+h)
    y_1 = 0; y1 = 0;
    h = 0;

    if isnumeric(input1) && length(input1) > 1
        % ----- DU LIEU BANG -----
        xa = input1; ya = input2;
        idx = find(xa == x0, 1);
        if isempty(idx), error('Khong tim thay x trong du lieu!'); end
        
        h = xa(2) - xa(1);
        
        if idx == 1 || idx == length(xa)
            error('Can du lieu o ca hai phia (khong tinh duoc tai bien)');
        end
        
        y_1 = ya(idx-1);
        y1  = ya(idx+1);
        
    else
        % ----- HAM SO -----
        ham_str = input1; h = input2;
        if isa(ham_str, 'function_handle'), f = ham_str; else, f = str2func(['@(x) ' ham_str]); end
        
        y_1 = f(x0 - h);
        y1  = f(x0 + h);
    end

    %% ===== CONG THUC DAO HAM TRUNG TAM =====
    % Cong thuc O(h^2): (f(x+h) - f(x-h)) / 2h
    df = (y1 - y_1) / (2*h);
end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VeButton
        function VeButtonPushed(app, event)
            fx = app.NhapPT.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            saiso = app.NhapSaiSo.Value;
            pp = app.ChonPPT.Value;

            switch pp
        case 'Chia đôi'
            [nghiem, solanlap] = app.chiadoi(fx,a,b,saiso);

        case 'Lặp'
            [nghiem, solanlap] = app.lap(fx,a,saiso);

        case'Newton'
            [nghiem, solanlap] = app.Newton(fx,a,b,saiso);
    end

    % Vẽ đồ thị hội tụ
    plot(app.UIAxes, solanlap, nghiem,'-o');
    xlabel(app.UIAxes,'Số lần lặp');
    ylabel(app.UIAxes,'Giá trị nghiệm');
    title(app.UIAxes,['Đồ thị hàm số ']);
    grid(app.UIAxes,'on');

    % Hiển thị kết quả cuối
    app.KetQuaNghiem.Value = nghiem(end);
    app.SoLanLap.Value = solanlap(end);
        end

        % Button pushed function: VeNoiSuy
        function VeNoiSuyButtonPushed(app, event)

            x_str = app.xNoiSuy.Value;   % lấy chuỗi người dùng nhập
            x = str2num(x_str);         %#ok<ST2NM>  chuyển sang mảng số
            y_str = app.yNoiSuy.Value;
            y = str2num(y_str);
            x_new = app.GiaTriCanNoiSuy.Value;
            pp = app.ChonPPNoiSuy.Value;

            switch pp
                case 'Lagrange'
            result = app.Lagrange(x,y,x_new);
            result_sym = app.LagrangeSym(x,y);

            x_plot = linspace(min(x), max(x), 500);
            y_plot = arrayfun(@(xx) app.Lagrange(x, y, xx), x_plot);

            % Vẽ điểm dữ liệu ban đầu
            plot(app.UIAxes2, x, y, 'ro', ...
                'MarkerSize', 8, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'on');

            % Vẽ đường nội suy Lagrange
            plot(app.UIAxes2, x_plot, y_plot, 'b-', ...
                'LineWidth', 2);

            % Đánh dấu điểm nội suy x_new
            plot(app.UIAxes2, x_new, result, 'ks', ...
                'MarkerSize', 8, 'LineWidth', 1.5);

            legend(app.UIAxes2, ...
                'Điểm dữ liệu ban đầu', ...
                'Hàm nội suy Lagrange', ...
                'Giá trị nội suy', ...
                'Location', 'best');

            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            title(app.UIAxes2, 'Nội suy Lagrange');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');

                case 'Newton'
            result = app.NewtonInterpolation(x, y, x_new);
            result_sym = app.NewtonSym(x, y);
            

            x_plot = linspace(min(x), max(x), 500);
            y_plot = arrayfun(@(xx) app.NewtonInterpolation(x, y, xx), x_plot);

            % Vẽ điểm dữ liệu ban đầu
            plot(app.UIAxes2, x, y, 'ro', ...
                'MarkerSize', 8, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'on');

            % Vẽ đường nội suy Lagrange
            plot(app.UIAxes2, x_plot, y_plot, 'b-', ...
                'LineWidth', 2);

            % Đánh dấu điểm nội suy x_new
            plot(app.UIAxes2, x_new, result, 'ks', ...
                'MarkerSize', 8, 'LineWidth', 1.5);

            legend(app.UIAxes2, ...
                'Điểm dữ liệu ban đầu', ...
                'Hàm nội suy Newton', ...
                'Giá trị nội suy', ...
                'Location', 'best');

            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            title(app.UIAxes2, 'Nội suy Newton');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');
            end
        
        % Hiển thị kết quả cuối
        app.KetQuaDaThucNoiSuy.Value = char(result_sym);
        app.KetQuaNoiSuy.Value = result(end);
        end

        % Button pushed function: TinhDaoHam
        function TinhDaoHamButtonPushed(app, event)
            x_str = app.xDaoHam.Value;   
            x = str2num(x_str);         
            y_str = app.yDaoHam.Value;
            y = str2num(y_str);
            x_0 = app.GiaTriCanTinhDaoHam.Value;
            h = app.hDaoHam.Value;
            ham_so = app.HamSoDaoHam.Value;
            pp = app.ChonPPDaoHam.Value;
            ss = app.ChonSaiSoDaoHam.Value;

            switch pp
                case 'Đạo hàm tiến'
                    switch ss
                        case 'O(h)'
                            sai_so = 1;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamTien(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamTien(x, y, x_0, sai_so);
                end             
                        case 'O(h^2)'
                            sai_so = 2;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamTien(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamTien(x, y, x_0, sai_so);
                end             
                    end

                case 'Đạo hàm lùi'
                    switch ss
                        case 'O(h)'
                            sai_so = 1;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamLui(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamLui(x, y, x_0, sai_so);
                end             
                        case 'O(h^2)'
                            sai_so = 2;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamLui(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamLui(x, y, x_0, sai_so);
                end             
                    end

                case 'Đạo hàm trung tâm'
                    switch ss
                        case 'O(h)'
                            sai_so = 1;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamTrungTam(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamTrungTam(x, y, x_0, sai_so);
                end                    
                        case 'O(h^2)'
                            sai_so = 2;
                if isempty(x) || isempty(y)
                    kq_df = app.DaoHamTrungTam(ham_so, h, x_0, sai_so);
                else
                    kq_df = app.DaoHamTrungTam(x, y, x_0, sai_so);
                end  
                    end
            end
            app.KetQuaDaoHam.Value = kq_df(end);
        end

        % Button pushed function: TinhTichPhan
        function TinhTichPhanPushed(app, event)
            x_str = app.xTichPhan.Value;   
            x = str2num(x_str);         
            y_str = app.yTichPhan.Value;
            y = str2num(y_str);
            a = app.Can_a.Value;
            b = app.Can_b.Value;
            f_str = app.NhapHamSoTichPhan.Value;
            if ~isempty(strtrim(f_str))
                f = str2func(['@(x)', f_str]);
            else
                f = [];   % KHONG DUNG HAM
            end
            N = app.NhapN.Value;
            pp = app.ChonPPTichPhan.Value;

            switch pp
                    case 'Hình thang'
                if isempty(x) || isempty(y)
                    % ===== DUNG HAM SO =====
                    kq = app.TichPhanHinhThang(f, a, b, N);
                else
                    % ===== DUNG MANG x, y =====
                    kq = app.TichPhanHinhThang(x, y);
                end
                    case 'Simpson 1/3'
                if isempty(x) || isempty(y)
                    % ===== DUNG HAM SO =====
                    kq = app.TichPhanSimpson13(f, a, b, N);
                else
                    % ===== DUNG MANG x, y =====
                    kq = app.TichPhanSimpson13(x, y);
                end

                    case 'Simpson 3/8'
                if isempty(x) || isempty(y)
                    % ===== DUNG HAM SO =====
                    kq = app.TichPhanSimpson38(f, a, b, N);
                else
                    % ===== DUNG MANG x, y =====
                    kq = app.TichPhanSimpson38(x, y);
                end
            end
                app.KetQuaTichPhan.Value = kq;
          
        end

        % Button pushed function: VeHoiQuy
        function VeHoiQuyButtonPushed(app, event)
            x_str = app.xHoiQuy.Value;   
            x = str2num(x_str);         
            y_str = app.yHoiQuy.Value;
            y = str2num(y_str);
            x_predict = app.NhapGTDuDoan.Value;
            pp = app.ChonPPHoiQuy.Value;
            x_plot = linspace(min(x), max(x), 200);

            switch pp
                case 'Tuyến tính'
                [a1, a0, r2] = app.HoiQuyTuyenTinh(x, y);
                y_plot = a1*x_plot + a0;
                y_predict = a1*x_predict + a0;
                result_sym = sprintf('y = %.4fx + %.4f   (R^2 = %.4f)', ...
                                  a1, a0, r2);
            
                case 'Hàm mũ'
                model = app.HoiQuyHamMu(x, y);
                if strcmp(model.type,'exp')
                y_plot = model.a * exp(model.b * x_plot);
                y_predict = model.a * exp(model.b * x_predict);

                result_sym = sprintf('y = %.4f e^{%.4fx}   (R^2 = %.4f)', ...
                                      model.a, model.b, model.r2);
                else
                y_plot = model.a * x_plot.^model.b;
                y_predict = model.a * x_predict.^model.b;
                result_sym = sprintf('y = %.4f x^{%.4f}   (R^2 = %.4f)', ...
                                      model.a, model.b, model.r2);
            end
                
                case 'Logarit'
            model = app.HoiQuyMu_e(x, y);
            % y = ae^(bx)
            y_plot = model.a*exp(model.b*x_plot);
            y_predict = model.a*exp(model.b*x_predict);
            result_sym = sprintf('y = %.4f + %.4f ln(x)   (R^2 = %.4f)', ...
                              model.a, model.b, model.r2);
            end
        
        % ===== VẼ ĐỒ THỊ =====
        cla(app.UIAxes3);
        plot(app.UIAxes3, x, y, 'b-', 'MarkerSize',8,'LineWidth',1.5);
        hold(app.UIAxes3,'on');
        plot(app.UIAxes3, x_plot, y_plot, 'r-', 'LineWidth',2);
        grid(app.UIAxes3,'on');    
        xlabel(app.UIAxes3,'x');
        ylabel(app.UIAxes3,'y');
        title(app.UIAxes3,['Hồi quy - ' pp]);
        legend(app.UIAxes3,'Dữ liệu thực','Đường hồi quy','Điểm dự đoán',...
               'Location','best');
        hold(app.UIAxes3,'off');

        % Hiển thị kết quả cuối
        app.KetQuaPTHoiQuy.Value = char(result_sym);
        app.KetQuaDuDoan.Value = y_predict(end);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimNghiem = uitab(app.TabGroup);
            app.TimNghiem.Title = 'Tìm Nghiệm';
            app.UIAxes = uiaxes(app.TimNghiem);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TimNghiem);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.TimNghiem, 'text');
            app.NhapPT.Value = 'x - sin(x) -0.25';
            app.ChonPPT = uidropdown(app.TimNghiem);
            app.ChonPPT.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.ChonPPT.Value = 'Chia đôi';
            app.VeButton = uibutton(app.TimNghiem, 'push');
            app.VeButton.ButtonPushedFcn = createCallbackFcn(app, @VeButtonPushed, true);
            app.VeButton.Text = 'Vẽ';
            app.KhongphnlinghimLabel = uilabel(app.TimNghiem);
            app.KhongphnlinghimLabel.Text = 'Khoảng phân li nghiệm';
            app.aEditField = uieditfield(app.TimNghiem, 'numeric');
            app.aEditField.Value = -1;
            app.bEditField = uieditfield(app.TimNghiem, 'numeric');
            app.bEditField.Value = 2;
            app.NhapSaiSo = uieditfield(app.TimNghiem, 'numeric');
            app.NhapSaiSo.Value = 0.005;
            app.KetQuaNghiem = uieditfield(app.TimNghiem, 'numeric');
            app.SoLanLap = uieditfield(app.TimNghiem, 'numeric');
            app.aLabel = uilabel(app.TimNghiem);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.TimNghiem);
            app.bLabel.Text = 'b';
            app.NhpkhongsaisLabel = uilabel(app.TimNghiem);
            app.NhpkhongsaisLabel.Text = 'Nhập khoảng sai số';
            app.ChnphngphptnhLabel = uilabel(app.TimNghiem);
            app.ChnphngphptnhLabel.Text = 'Chọn phương pháp tính';
            app.KtqunghimLabel = uilabel(app.TimNghiem);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.SlnlpLabel = uilabel(app.TimNghiem);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.UIAxes2 = uiaxes(app.NiSuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NiSuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonPPNoiSuy = uidropdown(app.NiSuyTab);
            app.ChonPPNoiSuy.Items = {'Lagrange', 'Newton'};
            app.ChonPPNoiSuy.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.GiaTriCanNoiSuy = uieditfield(app.NiSuyTab, 'numeric');
            app.GiaTriCanNoiSuy.Value = 0.11;
            app.KtqunisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KetQuaNoiSuy = uieditfield(app.NiSuyTab, 'numeric');
            app.VeNoiSuy = uibutton(app.NiSuyTab, 'push');
            app.VeNoiSuy.ButtonPushedFcn = createCallbackFcn(app, @VeNoiSuyButtonPushed, true);
            app.VeNoiSuy.Text = 'Vẽ';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KetQuaDaThucNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.yNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.yNoiSuy.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpdliuxEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.xNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.xNoiSuy.Value = '[0.1 0.2 0.3 0.4]';
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi Quy';
            app.UIAxes3 = uiaxes(app.HoiQuyTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.NhpdliuxEditFieldLabel_4 = uilabel(app.HoiQuyTab);
            app.NhpdliuxEditFieldLabel_4.Text = 'Nhập dữ liệu x';
            app.xHoiQuy = uieditfield(app.HoiQuyTab, 'text');
            app.xHoiQuy.Value = '[2.5 3.5 5 6 7.5 10 12.5 15 17.5 20]';
            app.NhpdliuyEditFieldLabel_4 = uilabel(app.HoiQuyTab);
            app.NhpdliuyEditFieldLabel_4.Text = 'Nhập dữ liệu y';
            app.yHoiQuy = uieditfield(app.HoiQuyTab, 'text');
            app.yHoiQuy.Value = '[13 11 8.5 8.2 7 6.2 5.2 4.8 4.6 4.3]';
            app.ChnphngphpDropDownLabel_2 = uilabel(app.HoiQuyTab);
            app.ChnphngphpDropDownLabel_2.Text = 'Chọn phương pháp';
            app.ChonPPHoiQuy = uidropdown(app.HoiQuyTab);
            app.ChonPPHoiQuy.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonPPHoiQuy.Value = 'Tuyến tính';
            app.NhpgitrcndonLabel = uilabel(app.HoiQuyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhapGTDuDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.NhapGTDuDoan.Value = 9;
            app.KtquphngtrnhLabel = uilabel(app.HoiQuyTab);
            app.KtquphngtrnhLabel.Text = 'Kết quả phương trình  ';
            app.KetQuaPTHoiQuy = uieditfield(app.HoiQuyTab, 'text');
            app.KtqudonEditFieldLabel = uilabel(app.HoiQuyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KetQuaDuDoan = uieditfield(app.HoiQuyTab, 'numeric');
            app.VeHoiQuy = uibutton(app.HoiQuyTab, 'push');
            app.VeHoiQuy.ButtonPushedFcn = createCallbackFcn(app, @VeHoiQuyButtonPushed, true);
            app.VeHoiQuy.Text = 'Vẽ';
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo Hàm';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.DaoHamTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x ';
            app.xDaoHam = uieditfield(app.DaoHamTab, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.DaoHamTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.yDaoHam = uieditfield(app.DaoHamTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.HamSoDaoHam = uieditfield(app.DaoHamTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.hDaoHam = uieditfield(app.DaoHamTab, 'numeric');
            app.ChnphngphpLabel = uilabel(app.DaoHamTab);
            app.ChnphngphpLabel.Text = 'Chọn phương pháp ';
            app.ChonPPDaoHam = uidropdown(app.DaoHamTab);
            app.ChonPPDaoHam.Items = {'Đạo hàm tiến', 'Đạo hàm lùi', 'Đạo hàm trung tâm'};
            app.ChonPPDaoHam.Value = 'Đạo hàm tiến';
            app.NhpgitrcntnhLabel = uilabel(app.DaoHamTab);
            app.NhpgitrcntnhLabel.Text = 'Nhập giá trị cần tính ';
            app.GiaTriCanTinhDaoHam = uieditfield(app.DaoHamTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.DaoHamTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQuaDaoHam = uieditfield(app.DaoHamTab, 'numeric');
            app.TinhDaoHam = uibutton(app.DaoHamTab, 'push');
            app.TinhDaoHam.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.TinhDaoHam.Text = 'Tính';
            app.ChngitrsaisDropDownLabel = uilabel(app.DaoHamTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số ';
            app.ChonSaiSoDaoHam = uidropdown(app.DaoHamTab);
            app.ChonSaiSoDaoHam.Items = {'O(h)', 'O(h^2)'};
            app.ChonSaiSoDaoHam.Value = 'O(h)';
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.TichPhanTab);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.xTichPhan = uieditfield(app.TichPhanTab, 'text');
            app.xTichPhan.Value = '[0.1 0.3 0.5 0.7 0.9]';
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.TichPhanTab);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.yTichPhan = uieditfield(app.TichPhanTab, 'text');
            app.yTichPhan.Value = '[0.1002 0.3047 0.5236 0.7754 1.1198]';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.NhapHamSoTichPhan = uieditfield(app.TichPhanTab, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.TichPhanTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonPPTichPhan = uidropdown(app.TichPhanTab);
            app.ChonPPTichPhan.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChonPPTichPhan.Value = 'Hình thang';
            app.KtquEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQuaTichPhan = uieditfield(app.TichPhanTab, 'numeric');
            app.TinhTichPhan = uibutton(app.TichPhanTab, 'push');
            app.TinhTichPhan.ButtonPushedFcn = createCallbackFcn(app, @TinhTichPhanPushed, true);
            app.TinhTichPhan.Text = 'Tính';
            app.NhpcnaEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpcnaEditFieldLabel.Text = 'Nhập cận a';
            app.Can_a = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpcnbEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpcnbEditFieldLabel.Text = 'Nhập cận b';
            app.Can_b = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapN = uieditfield(app.TichPhanTab, 'numeric');
            app.NhapN.Value = 10;
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Name4 = uilabel(app.GiithiunhmTab);
            app.Name4.Text = 'Nguyễn Minh Hiếu - 23207055';
            app.Name3 = uilabel(app.GiithiunhmTab);
            app.Name3.Text = 'Nguyễn Hoàng Long - 23207078';
            app.Name2 = uilabel(app.GiithiunhmTab);
            app.Name2.Text = 'Nguyễn Duy Nhật Minh - 23207084';
            app.Name1 = uilabel(app.GiithiunhmTab);
            app.Name1.Text = 'Huỳnh Bảo Nhật - 23207089';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_truong.png');
            app.Image2 = uiimage(app.GiithiunhmTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo-fetel.png');
            app.Title = uilabel(app.GiithiunhmTab);
            app.Title.Text = 'ĐỒ ÁN THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            app.Title1 = uilabel(app.GiithiunhmTab);
            app.Title1.Text = 'Giao diện Matlab giải quyết các bài toán phương pháp tính';
            app.Name = uilabel(app.GiithiunhmTab);
            app.Name.Text = 'Thành viên nhóm:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end