classdef TimNghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SoLanLap                     matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        KetQuaNghiem                 matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel      matlab.ui.control.Label
        NhapSaiSo                    matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        KhongphnlinghimLabel         matlab.ui.control.Label
        VeButton                     matlab.ui.control.Button
        ChonPPT                      matlab.ui.control.DropDown
        ChnphngphptnhDropDownLabel   matlab.ui.control.Label
        NhapPT                       matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function [c, n] = chiadoi(app,fx, a ,b ,saiso)
n = 0;
fxi = str2func(['@(x)', fx]);
while true 
c = (a + b)/2;
if(fxi(a)*fxi(c) < 0)
    b = c;
else 
    a = c;
end
n = n + 1;
if abs(b-a) < saiso
    break;
end
end
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VeButton
        function VeButtonPushed(app, event)
            fx = app.NhapPT.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            saiso = app.NhapSaiSo.Value;
            [nghiem, solanlap] = app.chiadoi(fx,a,b,saiso);
               % Vẽ đồ thị hội tụ
    plot(app.UIAxes, solanlap, nghiem,'-o');
    xlabel(app.UIAxes,'Số lần lặp');
    ylabel(app.UIAxes,'Giá trị nghiệm');
    grid(app.UIAxes,'on');

    % Hiển thị kết quả cuối
    app.KetQuaNghiem.Value = nghiem(end);
    app.SoLanLap.Value = solanlap(end);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.UIFigure, 'text');
            app.NhapPT.Value = 'x - sin(x) - 0.25';
            app.ChnphngphptnhDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphptnhDropDownLabel.Text = 'Chọn phương pháp tính';
            app.ChonPPT = uidropdown(app.UIFigure);
            app.ChonPPT.Items = {'Chia đôi', 'Option 2', 'Option 3', 'Option 4'};
            app.ChonPPT.Value = 'Chia đôi';
            app.VeButton = uibutton(app.UIFigure, 'push');
            app.VeButton.ButtonPushedFcn = createCallbackFcn(app, @VeButtonPushed, true);
            app.VeButton.Text = 'Vẽ';
            app.KhongphnlinghimLabel = uilabel(app.UIFigure);
            app.KhongphnlinghimLabel.Text = 'Khoảng phân li nghiệm';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 2;
            app.NhpsaischophpEditFieldLabel = uilabel(app.UIFigure);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhapSaiSo = uieditfield(app.UIFigure, 'numeric');
            app.NhapSaiSo.Value = 0.005;
            app.KtqunghimEditFieldLabel = uilabel(app.UIFigure);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.UIFigure, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.UIFigure);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimNghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end